classdef appDiskrVeriznica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        T1_ySlider       matlab.ui.control.Slider
        T1_ySliderLabel  matlab.ui.control.Label
        T1_xSlider       matlab.ui.control.Slider
        T1_xSliderLabel  matlab.ui.control.Label
        T0_ySlider       matlab.ui.control.Slider
        T0_ySliderLabel  matlab.ui.control.Label
        T0_xSlider       matlab.ui.control.Slider
        T0_xSliderLabel  matlab.ui.control.Label
        risanje          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        T0 = [0;0];
        T1 = [3;0];
        L = [2 1 1 1 1 2];
        M = [1 0.5 1 1 0.5 1];
        X = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: T0_xSlider
        function T0_xSliderValueChanging(app, event)
            changingValue = event.Value;
            app.T0(1) = changingValue;

            app.X = diskrVeriznica([-1,-1],app.T0,app.T1,app.L,app.M);
            plot(app.risanje,app.X(1,:),app.X(2,:),'bo-')
            app.risanje.XLim = [-3 6];
            app.risanje.YLim = [-5 5];
        end

        % Value changing function: T0_ySlider
        function T0_ySliderValueChanging(app, event)
            changingValue = event.Value;
            app.T0(2) = changingValue;

            app.X = diskrVeriznica([-1,-1],app.T0,app.T1,app.L,app.M);
            plot(app.risanje,app.X(1,:),app.X(2,:),'bo-')
            app.risanje.XLim = [-6 6];
            app.risanje.YLim = [-5 5];
        end

        % Value changing function: T1_xSlider
        function T1_xSliderValueChanging(app, event)
            changingValue = event.Value;
            app.T1(1) = changingValue;

            app.X = diskrVeriznica([-1,-1],app.T0,app.T1,app.L,app.M);
            plot(app.risanje,app.X(1,:),app.X(2,:),'bo-')
            app.risanje.XLim = [-3 6];
            app.risanje.YLim = [-5 5];
        end

        % Value changing function: T1_ySlider
        function T1_ySliderValueChanging(app, event)
            changingValue = event.Value;
            app.T1(2) = changingValue;

            app.X = diskrVeriznica([-1,-1],app.T0,app.T1,app.L,app.M);
            plot(app.risanje,app.X(1,:),app.X(2,:),'bo-')
            app.risanje.XLim = [-3 6];
            app.risanje.YLim = [-5 5];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.risanje = uiaxes(app.UIFigure);
            title(app.risanje, 'Diskretna veriznica')
            xlabel(app.risanje, 'X')
            ylabel(app.risanje, 'Y')
            zlabel(app.risanje, 'Z')
            app.T0_xSliderLabel = uilabel(app.UIFigure);
            app.T0_xSliderLabel.Text = 'T0_x';
            app.T0_xSlider = uislider(app.UIFigure);
            app.T0_xSlider.Limits = [-5 5];
            app.T0_xSlider.ValueChangingFcn = createCallbackFcn(app, @T0_xSliderValueChanging, true);
            app.T0_ySliderLabel = uilabel(app.UIFigure);
            app.T0_ySliderLabel.Text = 'T0_y';
            app.T0_ySlider = uislider(app.UIFigure);
            app.T0_ySlider.Limits = [-5 5];
            app.T0_ySlider.ValueChangingFcn = createCallbackFcn(app, @T0_ySliderValueChanging, true);
            app.T1_xSliderLabel = uilabel(app.UIFigure);
            app.T1_xSliderLabel.Text = 'T1_x';
            app.T1_xSlider = uislider(app.UIFigure);
            app.T1_xSlider.Limits = [-5 5];
            app.T1_xSlider.ValueChangingFcn = createCallbackFcn(app, @T1_xSliderValueChanging, true);
            app.T1_ySliderLabel = uilabel(app.UIFigure);
            app.T1_ySliderLabel.Text = 'T1_y';
            app.T1_ySlider = uislider(app.UIFigure);
            app.T1_ySlider.Limits = [-5 5];
            app.T1_ySlider.ValueChangingFcn = createCallbackFcn(app, @T1_ySliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDiskrVeriznica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end